classdef lab3gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ZPositionEditField       matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel  matlab.ui.control.Label
        YPositionEditField       matlab.ui.control.NumericEditField
        YPositionEditFieldLabel  matlab.ui.control.Label
        XPositionEditField       matlab.ui.control.NumericEditField
        XPositionEditFieldLabel  matlab.ui.control.Label
        IndicatorsSwitch         matlab.ui.control.ToggleSwitch
        IndicatorsSwitchLabel    matlab.ui.control.Label
        Image_2                  matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        ImageSlider              matlab.ui.control.Slider
        ImageSliderLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        imageNum % Slider Number for Image
        P        % Position of Ball
        indicatorBool = "Off"
        leftImageArray = ["left5.jpg","left6.jpg","left7.jpg","left8.jpg","left9.jpg"]; % Import Left Images
        rightImageArray = ["right5.jpg","right6.jpg","right7.jpg","right8.jpg","right9.jpg"]; % Import Right Images
    end
    
    methods (Access = private)
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changing function: ImageSlider
        function ImageSliderValueChanging(app, event)
            app.imageNum = round(event.Value);

            % Set Both Images
            app.Image.ImageSource = imread(app.leftImageArray(app.imageNum));
            app.Image_2.ImageSource = imread(app.rightImageArray(app.imageNum));

            % Read Both Images 
            leftImage = imread(app.leftImageArray(app.imageNum));
            rightImage = imread(app.rightImageArray(app.imageNum));


            % Show Default Image with Left and Right Camera
            %%imshowpair(leftImage,rightImage,'montage')
            
            % Normalize Image with Left and Right Camera
            normalizedImageLeft = im2double(imbinarize(leftImage,graythresh(leftImage)));
            normalizedImageRight = im2double(imbinarize(rightImage,graythresh(rightImage)));
            %figure
            %imshowpair(normalizedImageLeft,normalizedImageRight,'montage')
            
            % Set the image to Grayscale with Left and Right Camera
            grayscaleImageLeft = rgb2gray(normalizedImageLeft);
            grayscaleImageRight = rgb2gray(normalizedImageRight);
            %figure
            %imshowpair(grayscaleImageLeft,grayscaleImageRight,'montage')
            
            % Find Center and Radius of Left Ball
            [centerLeft, radiusLeft] = imfindcircles(grayscaleImageLeft, [20 100],'ObjectPolarity','bright', "Sensitivity",0.95);
            % Find Center and Radius of Right Ball
            [centerRight, radiusRight] = imfindcircles(grayscaleImageRight, [20 100],'ObjectPolarity','bright', "Sensitivity",0.95);
            
            % Add Visual Circle to Confirm viewing of the ball
            if app.indicatorBool == "On"
                
                imshow(grayscaleImageLeft)
                viscircles(centerLeft, radiusLeft,'EdgeColor','b');
                viscircles(centerRight, radiusRight,'EdgeColor','b');
                %saveas(f,'test','png');
                %app.Image.ImageSource = 'test.png';
                
                %%app.Image.ImageSource = cat(3, grayscaleImageLeft, grayscaleImageLeft, grayscaleImageLeft);

            end

            % Assumed Camera Variables
            b = 60;    % baseline [mm]
            f = 6;     % focal length [mm]
            ps = .006; % pixel size [mm]
            
            % Constants
            xNumPix = 752;            % total number of pixels in x direction of the sensor [px]
            yNumPix = 480;            % total number of pixels in y direction of the sensor [px]
            cxLeft  = xNumPix/2;      % left camera x center [px]
            cxRight = xNumPix/2;      % right camera x center [px]
            cyLeft  = yNumPix/2;      % left camera y center [px]
            cyRight = yNumPix/2;      % right camera y center [ 
              
            % Taking XY Elements from Left and Right Found Centers
            xLeft = centerLeft(1);
            yLeft = centerLeft(2);
            xRight = centerRight(1);
            yRight = centerRight(2);
            
            % Calculated Position of Ball
            d = (abs((xLeft-cxLeft)-(xRight-cxRight))*ps);  % disparity [mm]
            Z = (b * f)./d/1e3;                             % depth [mm]
            X = Z * ((xLeft-cxLeft) * ps)/f;                % left right pos [m]
            Y = Z * ((yLeft-cyLeft) * ps)/f;                % up down pos [m]
            
            
            % Update Labels for XYZ Positions
            app.XPositionEditField.Value = (X);
            app.YPositionEditField.Value = (Y);
            app.ZPositionEditField.Value = (Z);

            pause(0.10);
        end

        % Value changed function: IndicatorsSwitch
        function IndicatorsSwitchValueChanged(app, event)
            app.indicatorBool = app.IndicatorsSwitch.Value;
            if app.indicatorBool == "On"
              hold on;
              figure('Name','Camera View');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageSliderLabel = uilabel(app.UIFigure);
            app.ImageSliderLabel.Text = 'Image';
            app.ImageSlider = uislider(app.UIFigure);
            app.ImageSlider.Limits = [1 5];
            app.ImageSlider.MajorTicks = [1 2 3 4 5];
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderValueChanging, true);
            app.ImageSlider.MinorTicks = [1 2 3 4 5];
            app.ImageSlider.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'left5.jpg');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'right5.jpg');
            app.IndicatorsSwitchLabel = uilabel(app.UIFigure);
            app.IndicatorsSwitchLabel.Text = 'Indicators';
            app.IndicatorsSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IndicatorsSwitch.ValueChangedFcn = createCallbackFcn(app, @IndicatorsSwitchValueChanged, true);
            app.XPositionEditFieldLabel = uilabel(app.UIFigure);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.YPositionEditFieldLabel = uilabel(app.UIFigure);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZPositionEditFieldLabel = uilabel(app.UIFigure);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end