classdef lab4gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UpdateGraphButton          matlab.ui.control.Button
        ZTrueEditField             matlab.ui.control.NumericEditField
        ZTrueEditFieldLabel        matlab.ui.control.Label
        YTrueEditField             matlab.ui.control.NumericEditField
        YTrueEditFieldLabel        matlab.ui.control.Label
        XTrueEditField             matlab.ui.control.NumericEditField
        XTrueEditFieldLabel        matlab.ui.control.Label
        ServerConnectButton        matlab.ui.control.Button
        ZCalculatedEditField       matlab.ui.control.NumericEditField
        ZCalculatedEditFieldLabel  matlab.ui.control.Label
        YCalculatedEditField       matlab.ui.control.NumericEditField
        YCalculatedEditFieldLabel  matlab.ui.control.Label
        XCalculatedEditField       matlab.ui.control.NumericEditField
        XCalculatedEditFieldLabel  matlab.ui.control.Label
        IndicatorsSwitch           matlab.ui.control.ToggleSwitch
        IndicatorsSwitchLabel      matlab.ui.control.Label
        Image_2                    matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        ImageSlider                matlab.ui.control.Slider
        ImageSliderLabel           matlab.ui.control.Label
        AccuracyGraph              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imageNum % Slider Number for Image
        P        % Position of Ball
        indicatorBool = "Off"
        image_;image2_;
        width = 780;
        height = 480;
        ballPOS_ = zeros(3,5);
        image1Array_ = zeros(480,752,3,5,'uint8');
        image2Array_ = zeros(480,752,3,5,'uint8');
        graphArray = zeros(3,5);
    end
    
    methods (Access = private)
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("depthArray.mat","ballPOS","image1Array","image2Array")
            app.ballPOS_ = ballPOS;
            app.image1Array_ = image1Array;
            app.image2Array_ = image2Array;
        end

        % Value changing function: ImageSlider
        function ImageSliderValueChanging(app, event)
            app.imageNum = round(event.Value);
            
            % Stored Output Images to Not be in constant contact with server
            %% Move Ball to Selected Depth Value
            %blenderLink(client,width,height,(8-counter),8.5,0.45,45,0,0,"tennisBall");
            %% Grab Images From Blender
            %image_ = blenderLink(client,width,height,7.25,8.25,1,90,0,90,"Camera");
            %image2_ = blenderLink(client,width,height,7.25,8.85,1,90,0,90,"Camera");
            %set(gcf, 'Position', get(0, 'Screensize'));
            %axis off


            % Set Both Images
            app.Image.ImageSource = app.image1Array_(:,:,:,app.imageNum);
            app.Image_2.ImageSource = app.image2Array_(:,:,:,app.imageNum);

            % Read Both Images 
            leftImage = app.image1Array_(:,:,:,app.imageNum);
            rightImage = app.image2Array_(:,:,:,app.imageNum);


            % Show Default Image with Left and Right Camera
            %%imshowpair(leftImage,rightImage,'montage')
            
            % Normalize Image with Left and Right Camera
            normalizedImageLeft = im2double(imbinarize(leftImage,graythresh(leftImage)));
            normalizedImageRight = im2double(imbinarize(rightImage,graythresh(rightImage)));
            %figure
            %imshowpair(normalizedImageLeft,normalizedImageRight,'montage')
            
            % Set the image to Grayscale with Left and Right Camera
            grayscaleImageLeft = rgb2gray(normalizedImageLeft);
            grayscaleImageRight = rgb2gray(normalizedImageRight);
            %figure
            %imshowpair(grayscaleImageLeft,grayscaleImageRight,'montage')
            
            % Blur The Image
            blurLeft = imgaussfilt(grayscaleImageLeft,2);
            blurRight = imgaussfilt(grayscaleImageRight,2);

            % Apply an Edge Detection with a Sobel Filter
            sobelLeft = edge(blurLeft, 'Sobel');
            sobelRight = edge(blurRight, 'Sobel');

            
            % Find Center and Radius of Left Ball
            [centerLeft, radiusLeft] = imfindcircles(sobelLeft, [20 100],'ObjectPolarity','bright', "Sensitivity",0.9);
            % Find Center and Radius of Right Ball
            [centerRight, radiusRight] = imfindcircles(sobelRight, [20 100],'ObjectPolarity','bright', "Sensitivity",0.9);
            
            % Add Visual Circle to Confirm viewing of the ball
            if app.indicatorBool == "On"
                
                imshow(sobelLeft)
                viscircles(centerLeft, radiusLeft,'EdgeColor','b');
                viscircles(centerRight, radiusRight,'EdgeColor','b');
                %saveas(f,'test','png');
                %app.Image.ImageSource = 'test.png';
                
                %%app.Image.ImageSource = cat(3, grayscaleImageLeft, grayscaleImageLeft, grayscaleImageLeft);

            end

            % Assumed Camera Variables
            b = 600;    % baseline [mm]
            f = 6;     % focal length [mm]
            ps = .006; % pixel size [mm]
            
            % Constants
            xNumPix = 752;            % total number of pixels in x direction of the sensor [px]
            yNumPix = 480;            % total number of pixels in y direction of the sensor [px]
            cxLeft  = xNumPix/2;      % left camera x center [px]
            cxRight = xNumPix/2;      % right camera x center [px]
            cyLeft  = yNumPix/2;      % left camera y center [px]
            cyRight = yNumPix/2;      % right camera y center [ 
              
            % Taking XY Elements from Left and Right Found Centers
            xLeft = centerLeft(1);
            yLeft = centerLeft(2);
            xRight = centerRight(1);
            yRight = centerRight(2);
            
            % Calculated Position of Ball
            d = (abs((xLeft-cxLeft)-(xRight-cxRight))*ps);  % disparity [mm]
            Z = (b * f)./d/1e3;                             % depth [mm]
            X = Z * ((xLeft-cxLeft) * ps)/f;                % left right pos [m]
            Y = Z * ((yLeft-cyLeft) * ps)/f;                % up down pos [m]
            
            % Blender Y is our X
            % Blender X is our Z
            % Blender Z is our Y
            % Update Labels for XYZ Calculated Positions (Added to be in align with court)
            app.XCalculatedEditField.Value = 8 - (Z) - 0.75; % Minus Position of Camera
            app.YCalculatedEditField.Value = (Y) + 8.55;
            app.ZCalculatedEditField.Value = (X) + 0.65-0.55;

            % Update Labels for XYZ True Positions
            app.XTrueEditField.Value = app.ballPOS_(1,app.imageNum);
            app.YTrueEditField.Value = app.ballPOS_(2,app.imageNum);
            app.ZTrueEditField.Value = app.ballPOS_(3,app.imageNum);
            

            % Update Values for Accuracy Graph
            app.graphArray(1,app.imageNum) =  app.XCalculatedEditField.Value - app.XTrueEditField.Value;
            app.graphArray(2,app.imageNum) =  app.YCalculatedEditField.Value - app.YTrueEditField.Value;
            app.graphArray(3,app.imageNum) =  app.ZCalculatedEditField.Value - app.ZTrueEditField.Value;


            pause(0.10);
        end

        % Value changed function: IndicatorsSwitch
        function IndicatorsSwitchValueChanged(app, event)
            app.indicatorBool = app.IndicatorsSwitch.Value;
            if app.indicatorBool == "On"      
              figure('Name','Camera View');
              hold('Camera View', 'on');
            end
            
        end

        % Button pushed function: ServerConnectButton
        function ServerConnectButtonPushed(app, event)
clc;
clear all;
close all;

%Initialization Parameters
server_ip   = '127.0.0.1';     %IP address of the Unity Server
server_port = 55001;           %Server Port of the Unity Sever

client = tcpclient(server_ip,server_port,"Timeout",20);
fprintf(1,"Connected to server\n");
        end

        % Button pushed function: UpdateGraphButton
        function UpdateGraphButtonPushed(app, event)
            hold(app.AccuracyGraph, 'on');
            xArray = [1 2 3 4 5];
            plot(app.AccuracyGraph,xArray,app.graphArray(1,:),'g')
            plot(app.AccuracyGraph,xArray,app.graphArray(2,:),'m')
            plot(app.AccuracyGraph,xArray,app.graphArray(3,:),'c')
            legend(app.AccuracyGraph,'X','Y','Z','Location','east');
            hold(app.AccuracyGraph, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccuracyGraph = uiaxes(app.UIFigure);
            title(app.AccuracyGraph, 'Accuracy of Calculated - True')
            xlabel(app.AccuracyGraph, 'Image Number')
            ylabel(app.AccuracyGraph, 'Accuracy (m)')
            zlabel(app.AccuracyGraph, 'Z')
            app.AccuracyGraph.XLim = [1 5];
            app.AccuracyGraph.YLim = [-0.2 0.2];
            app.AccuracyGraph.XTick = [1 2 3 4 5];
            app.AccuracyGraph.XTickLabel = {'1'; '2'; '3'; '4'; '5'};
            app.AccuracyGraph.YTick = [-0.2 -0.15 -0.1 -0.05 0 0.05 0.1 0.15 0.2];
            app.AccuracyGraph.YTickLabel = {'-0.2'; '-0.15'; '-0.1'; '-0.05'; '0'; '0.05'; '0.1'; '0.15'; '0.2'};
            app.ImageSliderLabel = uilabel(app.UIFigure);
            app.ImageSliderLabel.Text = 'Image';
            app.ImageSlider = uislider(app.UIFigure);
            app.ImageSlider.Limits = [1 5];
            app.ImageSlider.MajorTicks = [1 2 3 4 5];
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderValueChanging, true);
            app.ImageSlider.MinorTicks = [1 2 3 4 5];
            app.ImageSlider.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'left5.jpg');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'right5.jpg');
            app.IndicatorsSwitchLabel = uilabel(app.UIFigure);
            app.IndicatorsSwitchLabel.Text = 'Indicators';
            app.IndicatorsSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IndicatorsSwitch.ValueChangedFcn = createCallbackFcn(app, @IndicatorsSwitchValueChanged, true);
            app.XCalculatedEditFieldLabel = uilabel(app.UIFigure);
            app.XCalculatedEditFieldLabel.Text = 'X Calculated';
            app.XCalculatedEditField = uieditfield(app.UIFigure, 'numeric');
            app.YCalculatedEditFieldLabel = uilabel(app.UIFigure);
            app.YCalculatedEditFieldLabel.Text = 'Y Calculated';
            app.YCalculatedEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZCalculatedEditFieldLabel = uilabel(app.UIFigure);
            app.ZCalculatedEditFieldLabel.Text = 'Z Calculated';
            app.ZCalculatedEditField = uieditfield(app.UIFigure, 'numeric');
            app.ServerConnectButton = uibutton(app.UIFigure, 'push');
            app.ServerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ServerConnectButtonPushed, true);
            app.ServerConnectButton.Text = 'Server Connect';
            app.XTrueEditFieldLabel = uilabel(app.UIFigure);
            app.XTrueEditFieldLabel.Text = 'X True';
            app.XTrueEditField = uieditfield(app.UIFigure, 'numeric');
            app.YTrueEditFieldLabel = uilabel(app.UIFigure);
            app.YTrueEditFieldLabel.Text = 'Y True';
            app.YTrueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZTrueEditFieldLabel = uilabel(app.UIFigure);
            app.ZTrueEditFieldLabel.Text = 'Z True';
            app.ZTrueEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateGraphButton = uibutton(app.UIFigure, 'push');
            app.UpdateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGraphButtonPushed, true);
            app.UpdateGraphButton.Text = 'Update Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab4gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end